classdef Display_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsDisplayUIFigure       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        mux1xButton                   matlab.ui.control.Button
        mux2Button                    matlab.ui.control.Button
        ClearPlotButton               matlab.ui.control.Button
        Steps1ClickplotbuttonLabel    matlab.ui.control.Label
        feigenbaumLabel               matlab.ui.control.Label
        ApproximatedValueLabel        matlab.ui.control.Label
        mu0EditField                  matlab.ui.control.NumericEditField
        mu0EditFieldLabel             matlab.ui.control.Label
        mu1EditField                  matlab.ui.control.NumericEditField
        mu1EditFieldLabel             matlab.ui.control.Label
        mu2EditField                  matlab.ui.control.NumericEditField
        mu2EditFieldLabel             matlab.ui.control.Label
        ApproximateFeigenbaumButton   matlab.ui.control.Button
        musinpixButton                matlab.ui.control.Button
        FeigenbaumApproximationLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        BruteForce                    matlab.ui.container.Tab
        iterationLabel                matlab.ui.control.Label
        feigenbaumLabel_2             matlab.ui.control.Label
        ApproximatedValueLabel_2      matlab.ui.control.Label
        BruteForceMethodusingNewtonsApproximationLabel  matlab.ui.control.Label
        ComputeButton                 matlab.ui.control.Button
        IterationsSlider              matlab.ui.control.Slider
        IterationsSliderLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins 
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Weight';
            app.UIAxes.YLabel.String = app.BloodPressureSwitch.Value;
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = 'off';
            app.BinWidthSlider.Visible = 'off';
        end
        
        function annotateHistogram(app)
           
            % Update X and Y Labels
            app.UIAxes.XLabel.String = app.BloodPressureSwitch.Value;
            app.UIAxes.YLabel.String = '# of Patients';
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = 'on';
            app.BinWidthSlider.Visible = 'on';
        end
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the smoker control
            if app.NoCheckBox.Value && ~app.YesCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 0];
            elseif app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 1];
            elseif  ~app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the gender control
            if app.MaleCheckBox.Value && ~app.FemaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Male"];
            elseif app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Female"];
            elseif  ~app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the location control
            if app.FunctionDropDown.Value ~= "All"
                tempIndices = [tempIndices, app.Data.Location == string(app.FunctionDropDown.Value)];
            end
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            %load('patients.mat','LastName','Gender','Smoker','Age','Height','Weight','Diastolic','Systolic','Location');
            
            % Store the data in a table and display it in one of the App's tabs.
            %app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            %app.UITable.Data = app.Data;
            
            % Update the axes with the corresponding data.
            %updateSelectedGenders(app)
            refreshplot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {329, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function refreshplot(app, event)
           
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')

            % Select relevant segment of data
            
            % Create either a scatter plot or histogram, based on selection
            
            
            % Update the table to show only the data that satisfies the controls
            drawnow;
        end

        % Button pushed function: musinpixButton
        function musinpixButtonPushed(app, event)
    
            mu=linspace(0,1,5000); % Values of the parameter mu to use
            N_iter=4000; % iterate for each mu value
            N_discard=3950; % iterations to discard
            x=zeros(length(mu), N_iter); % bifurcation diagram data
            x(:,1)=0.01; % init value
            for i=1:length(mu) % step through the values of mu to produce the bifur. diag.
                for j=2:N_iter
                    x(i,j)=mu(i)*sin(pi*x(i,j-1));
                end
            end 
            plot(app.UIAxes, mu,x(:,N_discard+1:end),'black.','markersize',1)
        end

        % Value changed function: mu2EditField
        function mu2EditFieldValueChanged(app, event)
            format long
            persistent lineHandle_mu2
            value = vpa(app.mu2EditField.Value);
            app.Data.mu2 = value;
            %plot(app.UIAxes, [value value], [0 1], 'r');
            if isempty(lineHandle_mu2) || ~isvalid(lineHandle_mu2)
                lineHandle_mu2 = xline(app.UIAxes, 0, 'r-');
            end
            lineHandle_mu2.Value = double(value);
        end

        % Value changed function: mu1EditField
        function mu1EditFieldValueChanged(app, event)
            format long
            persistent lineHandle_mu1
            value = vpa(app.mu1EditField.Value);
            app.Data.mu1 = value;
            %plot(app.UIAxes, [value value], [0 1], 'r');
            if isempty(lineHandle_mu1) || ~isvalid(lineHandle_mu1)
                lineHandle_mu1 = xline(app.UIAxes, 0, 'r-');
            end
            lineHandle_mu1.Value = double(value);
        end

        % Value changed function: mu0EditField
        function mu0EditFieldValueChanged(app, event)
            format long
            persistent lineHandle_mu0
            value = vpa(app.mu0EditField.Value);
            app.Data.mu0 = value;
            %plot(app.UIAxes, [value value], [0 1], 'r');
            if isempty(lineHandle_mu0) || ~isvalid(lineHandle_mu0)
                lineHandle_mu0 = xline(app.UIAxes, 0, 'r-');
            end
            lineHandle_mu0.Value = double(value);            
        end

        % Button pushed function: ApproximateFeigenbaumButton
        function ApproximateFeigenbaumButtonPushed(app, event)
            mu0 = app.Data.mu0;
            mu1 = app.Data.mu1;
            mu2 = app.Data.mu2;
            feigenbaum = (mu1 - mu2)/(mu0-mu1);
            app.feigenbaumLabel.Text = string(feigenbaum);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes) 
        end

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            value = app.IterationsSlider.Value;
            app.Data.num_iter = round(value);
            app.iterationLabel.Text = string(round(value));
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            format long
            max_iter = app.Data.num_iter;
            max_iter_j = app.Data.num_iter;
            mu1 = 4;
            mu2 = 0;
            feigenbaum1 = 3.2;
            
            %% main
            % newton's method
            % http://keithbriggs.info/documents/how-to-calc.pdf
            
            for i = 2 : max_iter
                % update mu value and store
                mu = vpa(mu1 + (mu1 - mu2) / feigenbaum1);
                for j = 1 : max_iter_j
                    dx = 0;
                    x = 0;
                    % loop for period doubling 2^index & adjust mu 
                    for k = 1 : bitshift(2, i - 1)
                        dx = 1 - (2 * dx * x);
                        x = mu - (x * x);
                    end
                    mu = mu - (x / dx);
                end
                % update feigenbaum value and store
                feigenbaum = (mu1 - mu2) / (mu - mu1);
                feigenbaum1 = feigenbaum;
                mu2 = mu1;
                mu1 = mu;
               
            end
            app.feigenbaumLabel_2.Text = string(feigenbaum);
        end

        % Button pushed function: mux2Button
        function mux2ButtonPushed(app, event)
            mu=linspace(0,2,6000); % Values of the parameter mu to use
            N_iter=4000; % iterate for each mu value
            N_discard=3950; % iterations to discard
            x=zeros(length(mu), N_iter); % bifurcation diagram data
            x(:,1)=0.01; % init value
            for i=1:length(mu) % step through the values of mu to produce the bifur. diag.
                for j=2:N_iter
                    x(i,j)=mu(i) - x(i,j-1) * x(i,j-1);
                end
            end 
            plot(app.UIAxes, mu,x(:,N_discard+1:end),'black.','markersize',1)
        end

        % Button pushed function: mux1xButton
        function mux1xButtonPushed(app, event)
            mu=linspace(0,5,6000); % Values of the parameter mu to use
            N_iter=4000; % iterate for each mu value
            N_discard=3950; % iterations to discard
            x=zeros(length(mu), N_iter); % bifurcation diagram data
            x(:,1)=0.01; % init value
            for i=1:length(mu) % step through the values of mu to produce the bifur. diag.
                for j=2:N_iter
                    x(i,j)=mu(i)*x(i,j-1)*(1-x(i,j-1));
                end
            end 
            plot(app.UIAxes, mu,x(:,N_discard+1:end),'black.','markersize',1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsDisplayUIFigure = uifigure('Visible', 'off');
            app.PatientsDisplayUIFigure.Name = 'Patients Display';
            app.PatientsDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FeigenbaumApproximationLabel = uilabel(app.LeftPanel);
            app.FeigenbaumApproximationLabel.Text = 'Feigenbaum Approximation';
            app.musinpixButton = uibutton(app.LeftPanel, 'push');
            app.musinpixButton.ButtonPushedFcn = createCallbackFcn(app, @musinpixButtonPushed, true);
            app.musinpixButton.Text = ' mu * sin(pi*x)';
            app.ApproximateFeigenbaumButton = uibutton(app.LeftPanel, 'push');
            app.ApproximateFeigenbaumButton.ButtonPushedFcn = createCallbackFcn(app, @ApproximateFeigenbaumButtonPushed, true);
            app.ApproximateFeigenbaumButton.Text = 'Approximate Feigenbaum';
            app.mu2EditFieldLabel = uilabel(app.LeftPanel);
            app.mu2EditFieldLabel.Text = 'mu-2';
            app.mu2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.mu2EditField.ValueChangedFcn = createCallbackFcn(app, @mu2EditFieldValueChanged, true);
            app.mu1EditFieldLabel = uilabel(app.LeftPanel);
            app.mu1EditFieldLabel.Text = 'mu-1';
            app.mu1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.mu1EditField.ValueChangedFcn = createCallbackFcn(app, @mu1EditFieldValueChanged, true);
            app.mu0EditFieldLabel = uilabel(app.LeftPanel);
            app.mu0EditFieldLabel.Text = 'mu-0';
            app.mu0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.mu0EditField.ValueChangedFcn = createCallbackFcn(app, @mu0EditFieldValueChanged, true);
            app.ApproximatedValueLabel = uilabel(app.LeftPanel);
            app.ApproximatedValueLabel.Text = 'Approximated Value:';
            app.feigenbaumLabel = uilabel(app.LeftPanel);
            app.feigenbaumLabel.Text = '0.0';
            app.Steps1ClickplotbuttonLabel = uilabel(app.LeftPanel);
            app.Steps1ClickplotbuttonLabel.Text = {'Steps:'; '1. Click plot button'; '2. Approximate bifurcation points'; '3. Click feigenbaum button'};
            app.ClearPlotButton = uibutton(app.LeftPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.mux2Button = uibutton(app.LeftPanel, 'push');
            app.mux2Button.ButtonPushedFcn = createCallbackFcn(app, @mux2ButtonPushed, true);
            app.mux2Button.Text = 'mu - x^2';
            app.mux1xButton = uibutton(app.LeftPanel, 'push');
            app.mux1xButton.ButtonPushedFcn = createCallbackFcn(app, @mux1xButtonPushed, true);
            app.mux1xButton.Text = 'mu*x*(1-x)';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Bifurcation Plot')
            xlabel(app.UIAxes, 'mu')
            ylabel(app.UIAxes, 'X values')
            app.BruteForce = uitab(app.TabGroup);
            app.BruteForce.Title = 'Alternate Method';
            app.IterationsSliderLabel = uilabel(app.BruteForce);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.BruteForce);
            app.IterationsSlider.Limits = [2 14];
            app.IterationsSlider.MajorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14];
            app.IterationsSlider.MajorTickLabels = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.IterationsSlider.MinorTicks = [];
            app.IterationsSlider.Value = 2;
            app.ComputeButton = uibutton(app.BruteForce, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.BruteForceMethodusingNewtonsApproximationLabel = uilabel(app.BruteForce);
            app.BruteForceMethodusingNewtonsApproximationLabel.Text = 'Brute Force Method using Newton''s Approximation';
            app.ApproximatedValueLabel_2 = uilabel(app.BruteForce);
            app.ApproximatedValueLabel_2.Text = 'Approximated Value:';
            app.feigenbaumLabel_2 = uilabel(app.BruteForce);
            app.feigenbaumLabel_2.Text = '0.0';
            app.iterationLabel = uilabel(app.BruteForce);
            app.iterationLabel.Text = '0.0';
            % Show the figure after all components are created
            app.PatientsDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Display_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsDisplayUIFigure)
        end
    end
end